classdef climatewedges < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        WedgesFileNameWithoutQuotesEditFieldLabel  matlab.ui.control.Label
        WedgesFileNameWithoutQuotesEditField  matlab.ui.control.EditField
        ConstraintsFileNameWithoutQuotesEditFieldLabel  matlab.ui.control.Label
        ConstraintsFileNameWithoutQuotesEditField  matlab.ui.control.EditField
        StrategyFileNameWithoutQuotesEditFieldLabel  matlab.ui.control.Label
        StrategyFileNameWithoutQuotesEditField  matlab.ui.control.EditField
        ReadFilesButton                matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        UIAxes                         matlab.ui.control.UIAxes
        SavetheWorldLabel              matlab.ui.control.Label
        OutputStrategyButton           matlab.ui.control.Button
        Step1ReadFilesLabel            matlab.ui.control.Label
        Step2SelectAdditionalWedgesLabel  matlab.ui.control.Label
        Step3OutputStrategyLabel       matlab.ui.control.Label
        UITable2                       matlab.ui.control.Table
        WedgesLabel                    matlab.ui.control.Label
        ConstraintsLabel               matlab.ui.control.Label
        ClearAllButton                 matlab.ui.control.Button
        TotalNumberofWedgesUsedEditFieldLabel  matlab.ui.control.Label
        TotalNumberofWedgesUsedEditField  matlab.ui.control.NumericEditField
        EditFieldLabel                 matlab.ui.control.Label
        EditField                      matlab.ui.control.NumericEditField
        TotalCostofWedgesEditFieldLabel  matlab.ui.control.Label
        TotalCostofWedgesEditField     matlab.ui.control.NumericEditField
        NumberofWedgestoReachDropDownLabel  matlab.ui.control.Label
        NumberofWedgestoReachDropDown  matlab.ui.control.DropDown
        GraphDropDownLabel             matlab.ui.control.Label
        GraphDropDown                  matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        wedge_data;
        wedge_data1;
        header;
        XData;
        YData;
        Constraints;
        Constraints1;
        Constraints2;
        Constraints_header;
        Strategies;
        Strategy_header;
        Count;
        Output;
        Carbon_emissions;
        Carbon_emissions1;
        Carbon_emissions2;
        Graph_legend;
        legend_wedges;
        Legend;
        Selected_Wedges;
        % Initialize variables
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fileID = fopen('historicaldata.txt','r');
            header1 = fgetl(fileID);
            app.Carbon_emissions = 10*2^((2068-2017)/50);
            app.Carbon_emissions1 = app.Carbon_emissions;
            
            data = [];
            while ~feof(fileID)
                getdata = fgetl(fileID);
                u = str2num(getdata);
                data = [data; u];
            end
            
            app.XData = data(:,1);
            app.XData = [app.XData; 2018; 2068];
            app.YData = data(:,2);
            app.YData = [app.YData; 10*2^((2018-2017)/50); app.Carbon_emissions];
            
            
            plot(app.UIAxes,app.XData,app.YData, '-g');
            app.UIAxes.XLim = [1959, 2068];
            
            app.NumberofWedgestoReachDropDown.Items = {'flatline emissions', '1990 level emissions', '80% below 1990 level emissions'};
            app.GraphDropDown.Items = {'Carbon Emissions', 'CO2 Concentration vs. Time', 'Mean Warming vs. Time'};
            
        end

        % Button pushed function: ReadFilesButton
        function ReadFilesButtonPushed(app, event)
            value1 = app.WedgesFileNameWithoutQuotesEditField.Value;
            % Initialize variables
            % Make Sounds for errors when applicable
            % Use Warndlg for error
            
            try
                if strfind(value1,'wedges')
                    FID = fopen(value1);
                    app.header = fgetl(FID);
                    % If file is correct, open text file and extract first line
                    
                    app.UITable.ColumnName = [strsplit(app.header, ';'), {'SelectedWedges'}, {'NumberUsed'}, {'StartYear'}];
                    % Make first line of file the header of the table
                    app.wedge_data = {};
                    while ~feof(FID)
                        x = strsplit(fgetl(FID), ';');
                        app.wedge_data = [app.wedge_data ; x, false, 0, ' '];
                    end % end while
                    % Extract each line of the file
                    
                    app.UITable.Data = {};
                    app.UITable.Data = app.wedge_data;
                    app.UITable.ColumnEditable = true;
                    app.wedge_data1 = app.wedge_data;
                    % Display the data in the Table
                    
                elseif isempty(value1)
                    warndlg('Please input a wedges file', 'Error');
                    return
                else
                    warndlg('Incorrect wedges file name entered. File name must include the word ''wedges''.', 'Error');
                    return
                    % If the wrong file is selected, display error message
                end % end if
            catch
                warndlg('Wedges file not found in MATLAB path', 'Error');
                return
                % If the file is not in the MATLAB path, display the error message
            end % end try
            % ------------------------------------------------------------------------------------------------------------------------------------------------
            value2 = app.ConstraintsFileNameWithoutQuotesEditField.Value;
            data = {};
            % Initialize variables
            
            try
                if strfind(value2,'constraints')
                    % If the correct file is selected, extract each line
                    % May want to use strsplit to extract data
                    FID = fopen(value2);
                    app.Constraints_header = fgetl(FID);
                    while ~feof(FID)
                        x = strsplit(fgetl(FID), ' ');
                        data = [data; x];
                    end % end while
                    app.Constraints = data;
                    app.Constraints1 = data;
                    
                    
                    app.UITable2.ColumnName = strsplit(app.Constraints_header, ' ');
                    app.UITable2.Data = app.Constraints;
                    % Display the data in the appropriate text area


                elseif isempty(value2)
                    warndlg('Please select a constraints file');
                    return
                else
                    warndlg('Incorrect constraints file name entered. File name must include the word ''constraints''.', 'Error');
                    return
                    % If the wrong file is selected, display error message
                end % end if
            catch
                warndlg('Constraints file not found in MATLAB path', 'Error');
                return
                % If the file is not in the MATLAB path, display error message
            end % end try
            % ------------------------------------------------------------------------------------------------------------------------------------------------
            value3 = app.StrategyFileNameWithoutQuotesEditField.Value;
            data = {};
            app.Graph_legend = {};
            wedges = [];
            app.TotalCostofWedgesEditField.Value = 0;
            % Initialize variables
            
            try
                if strfind(value3,'strategy')
                    % If the correct file is selected, extract each line
                    % May want to use strsplit to extract data
                    FID = fopen(value3);
                    app.Strategy_header = fgetl(FID);
                    while ~feof(FID)
                        x = strsplit(fgetl(FID), ' ');
                        data = [data; x];
                    end % end while
                    
                    app.Strategies = data;


                    [r, ~] = size(app.Strategies);
                    header1 = strsplit(app.header, ';');
                    [r2, ~] = size(app.Constraints);
                    
                    
                elseif isempty(value3)
                    return
                else
                    warndlg('Incorrect strategy file name entered. File name must include the word ''strategy''.', 'Error');
                    return
                    % If the wrong file is selected, display error message
                end % end if
            catch
                warndlg('Strategy file not found in MATLAB path', 'Error');
                return
                % If the file is not in the MATLAB path, display error message
            end % end try
            
            
            header1 = app.UITable.ColumnName;
            header4 = app.UITable2.ColumnName;
            [r2, ~] = size(app.Strategies);
            [r3, ~] = size(app.Constraints);
            header3 = strsplit(app.Strategy_header, ' ');
            
            index1 = find(contains(header1, 'Strategy'));
            index3 = find(contains(header1, 'Sector'));
            index4 = find(contains(header4, 'Sector'));
            index5 = find(contains(header4, 'Constraints'));
            index6 = find(contains(header3, 'ID'));
            index7 = find(contains(header1, 'Category'));
            index8 = find(contains(header3, 'YearStart'));
            index9 = find(contains(header1, 'StrategyName'));
            index10 = find(contains(header1, 'SelectedWedges'));
            index11 = find(contains(header1, 'NumberUsed'));
            index12 = find(contains(header1, 'StartYear'));
            index13 = find(contains(header1, 'Cost'));
            
            if strcmp(app.GraphDropDown.Value, 'Carbon Emissions');
                hold(app.UIAxes, 'off');
                
                plot(app.UIAxes,app.XData,app.YData, '-g');
                app.UIAxes.XLim = [1959, 2068];
                
                hold(app.UIAxes, 'on');
            end % end if
            
            app.Carbon_emissions = app.Carbon_emissions1;
            
            for j = 1:r
                
                IDnumber = str2num(app.Strategies{j, index6});
                if app.wedge_data{IDnumber, index10} == false
                    app.wedge_data{IDnumber, index10} = true;
                    app.wedge_data{IDnumber, index11} = 1;
                    app.wedge_data{IDnumber, index12} = app.Strategies{j, index8};
                    
                    app.UITable.Data = app.wedge_data;
                else
                    app.wedge_data{IDnumber, index11} = app.wedge_data{IDnumber, index11} + 1;
                    app.UITable.Data = app.wedge_data;
                end % end if
                
                Constraint = strsplit(app.wedge_data{str2num(app.Strategies{j, index6}), index3}, ',');
                for m = 1:r3
                    for k = 1:numel(Constraint)
                        if strcmp(string(Constraint(k)), string(app.Constraints{m, index4}))
                            app.Constraints{m, index5} = num2str(str2num(app.Constraints{m, index5})-1);
                            app.UITable2.Data = app.Constraints;
                        end % end if
                        if str2num(app.Constraints{m, index5}) <= 0
                            warndlg('Too many files of desired sector. Please follow given constraints.');
                            return
                        end % end if
                    end % end for
                end % end for
                
                hold(app.UIAxes,'on');
                
                m = (10*2^((2068-2017)/50)-10*2^((2018-2017)/50))/(2068-2018);
                b = (10*2^((2018-2017)/50)-m*2018);
                
                
                if strcmp(app.GraphDropDown.Value, 'Carbon Emissions')
                    xdata1 = [str2num(app.Strategies{j, index8}), 2068];
                    ydata1 = [m*str2num(data{j, index8})+b, app.Carbon_emissions];
                    plot(app.UIAxes,xdata1,ydata1);
                    [r, ~] = size(app.Strategies);
                    
                    app.Carbon_emissions = app.Carbon_emissions - (2068-str2num(app.Strategies{j, index8}))/50;
                    
                    xdata2 = [str2num(app.Strategies{j, index8}), 2068];
                    ydata2 = [m*str2num(app.Strategies{j, index8})+b, app.Carbon_emissions];
                    plot(app.UIAxes, xdata2, ydata2);
                    
                    
                    hold(app.UIAxes,'on');
                    
                    Category = strsplit(app.wedge_data{str2num(data{j, index6}), index7}, ',');
                    if strcmp('Efficiency and Conservation', Category)
                        k = fill(app.UIAxes, [xdata1, xdata2(2)], [ydata1, ydata2(2)], 'y');
                    elseif strcmp('Fossil Fuel', Category)
                        k = fill(app.UIAxes, [xdata1, xdata2(2)], [ydata1, ydata2(2)], 'b');
                    elseif strcmp('Nuclear', Category)
                        k = fill(app.UIAxes, [xdata1, xdata2(2)], [ydata1, ydata2(2)], 'r');
                    elseif strcmp('Renewables and Biostorage', Category)
                        k = fill(app.UIAxes, [xdata1, xdata2(2)], [ydata1, ydata2(2)], 'g');
                    end % end if


                    if numel(app.Graph_legend) == 0
                        app.Graph_legend = {app.wedge_data{IDnumber, index9}};
                        app.legend_wedges = [k];
                        app.Legend = legend(app.UIAxes, app.legend_wedges, app.Graph_legend{:}, 'Location', 'northwest');
                    else
                        app.Graph_legend = [app.Graph_legend, app.wedge_data{IDnumber, index9}];
                        app.legend_wedges = [app.legend_wedges, k];
                    end % end if
                    delete(app.Legend)
                    app.Legend = legend(app.UIAxes, app.legend_wedges, app.Graph_legend{:}, 'Location', 'northwest');
                end % end if
                
                app.TotalCostofWedgesEditField.Value = app.TotalCostofWedgesEditField.Value + str2num(app.wedge_data{IDnumber, index13});
                
            end % end for
            
            
            if strcmp(app.GraphDropDown.Value, 'Carbon Emissions')
                flatlinex = [str2num(app.Strategies{j, index8}), 2068];
                flatliney = [m*str2num(data{j, index8})+b, m*str2num(data{j, index8})+b];
                plot(app.UIAxes, flatlinex, flatliney, 'k');
                app.Legend = legend(app.UIAxes, app.legend_wedges, app.Graph_legend{:}, 'Location', 'northwest');
                
                app.Constraints = app.Constraints2;
                
                app.TotalNumberofWedgesUsedEditField.Value = sum([app.wedge_data{:, index11}]);
                
                flatlineyear = str2num(app.Strategies{j, index8});
                
                plot(app.UIAxes, flatlinex, flatliney, 'k');
                app.Legend = legend(app.UIAxes, app.legend_wedges, app.Graph_legend{:}, 'Location', 'northwest');
                
                delete(app.Legend)
                app.Legend = legend(app.UIAxes, app.legend_wedges, app.Graph_legend{:}, 'Location', 'northwest');
            end % end if
            
            flatliney = [m*str2num(data{j, index8})+b, m*str2num(data{j, index8})+b];
            flatlineyear = str2num(app.Strategies{j, index8});
            app.Carbon_emissions = app.Carbon_emissions1;
            
            if strcmp(app.NumberofWedgestoReachDropDown.Value, 'flatline emissions')
                app.Count = 0;
                while app.Carbon_emissions > flatliney(1)
                    app.Count = app.Count + 1;
                    app.Carbon_emissions = app.Carbon_emissions - (2068-flatlineyear)/50;
                end % end while
                
                app.EditField.Value = sum([app.wedge_data{:, index11}]) + app.Count;
            elseif strcmp(app.NumberofWedgestoReachDropDown.Value, '1990 level emissions')
                app.Count = 0;
                while app.Carbon_emissions > 6.06
                    app.Count = app.Count + 1;
                    app.Carbon_emissions = app.Carbon_emissions - (2068-flatlineyear)/50;
                end % end while
            elseif strcmp(app.NumberofWedgestoReachDropDown.Value, '80% below 1990 level emissions')
                app.Count = 0;
                while app.Carbon_emissions > 6.06*0.2
                    app.Count = app.Count + 1;
                    app.Carbon_emissions = app.Carbon_emissions - (2068-flatlineyear)/50;
                end % end while
            end % end if
            
            if strcmp(app.GraphDropDown.Value, 'CO2 Emissions vs. Time')
                for j = 1:length(app.XData)-2
                    cydata(j+1) = cxdata(j)+1*0.5*(10*2^((app.XData(j)-2017)/50));
                    
                end % end for
                
                plot(app.UIAxes, app.Xdata, cydata);
                
            end % end if
            
            app.EditField.Value = app.Count;
            
            app.TotalNumberofWedgesUsedEditField.Value = sum([app.wedge_data{:, index11}]);


        end

        % Button pushed function: OutputStrategyButton
        function OutputStrategyButtonPushed(app, event)
            
            
            header1 = strsplit(app.header, ';');
            fid = fopen('output.txt', 'wt');
            headerline = ['ID;StrategyName;Sector;NumberUsed;TotalCost;Challenges'];
            fprintf(fid, headerline);
            app.Output = [];
            line = {};


            for r = 2:numel(app.SelectedWedgesTextArea.Value)-1
                app.Count = 1;
                for q = r+1:numel(app.SelectedWedgesTextArea.Value)
                    if strcmp(app.SelectedWedgesTextArea.Value(r), app.SelectedWedgesTextArea.Value(q))
                        app.Count = app.Count + 1;
                    end % end if
                end % end for
                
                if app.Count > numel(app.SelectedWedgesTextArea.Value)/2 && app.Count > 1
                    warndlg('One wedge cannot account for more than half of the selected wedges', 'Error');
                    return
                end % end if
                
                index1 = find(contains(header1, 'ID'));
                index2 = find(contains(header1, 'StrategyName'));
                index3 = find(contains(header1, 'Sector'));
                index5 = find(contains(header1, 'Cost'));
                index6 = find(contains(header1, 'Challenges'));
                
                
                IDnumber1 = strsplit(char(app.SelectedWedgesTextArea.Value(r)));
                IDnumber2 = str2num(char(IDnumber1{1}));
                
                if numel(line) == 0
                    line = {cat(1, app.wedge_data{IDnumber2, index1}, {';'},  app.wedge_data{IDnumber2, index2},  {';'}, app.wedge_data{IDnumber2, index3}, {';'}, num2str(app.Count), {';'}, num2str(str2num(char(app.wedge_data{IDnumber2, index5}))*app.Count), {';'}, app.wedge_data{IDnumber2, index6})};
                else
                    line = [line; {app.wedge_data{IDnumber2, index1}, ';', app.wedge_data{IDnumber2, index2}, ';', app.wedge_data{IDnumber2, index3}, ';', num2str(app.Count), ';', num2str(str2num(char(app.wedge_data{IDnumber2, index5}))*app.Count), ';', app.wedge_data{IDnumber2, index6}}];
                end % end if


                %                     fprintf(fid, '\n%s', line)


            end % end for
            
            
            r = numel(app.SelectedWedgesTextArea.Value);
            
            IDnumber1 = strsplit(char(app.SelectedWedgesTextArea.Value(r)));
            IDnumber2 = str2num(char(IDnumber1{1}));
            
            line = [app.wedge_data{IDnumber2, index1}, ';', app.wedge_data{IDnumber2, index2}, ';', app.wedge_data{IDnumber2, index3}, ';', num2str(app.Count), ';', num2str(str2num(char(app.wedge_data{IDnumber2, index5}))*app.Count), ';', app.wedge_data{IDnumber2, index6}];
            
            fprintf(fid, '\n%s\n', line)


            fclose(fid);


        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            [r1, ~] = size(app.wedge_data);
            
            header1 = app.UITable.ColumnName;
            header4 = app.UITable2.ColumnName;
            header5 = strsplit(app.Strategy_header, ' ');
            header7 = app.UITable2.ColumnName;
            app.UITable2.Data = app.Constraints1;
            header3 = strsplit(app.Strategy_header, ' ');
            
            index1 = find(contains(header1, 'Strategy'));
            index3 = find(contains(header1, 'Sector'));
            index4 = find(contains(header4, 'Sector'));
            index5 = find(contains(header4, 'Constraints'));
            index6 = find(contains(header3, 'ID'));
            index7 = find(contains(header1, 'Category'));
            index8 = find(contains(header5, 'YearStart'));
            index9 = find(contains(header1, 'StrategyName'));
            index10 = find(contains(header1, 'SelectedWedges'));
            index11 = find(contains(header1, 'NumberUsed'));
            index12 = find(contains(header1, 'Cost'));
            index13 = find(contains(header1, 'StartYear'));
            app.wedge_data = app.UITable.Data;
            app.Constraints = app.Constraints1;
            [r2, ~] = size(app.Constraints);
            
            hold(app.UIAxes, 'off');
            
            plot(app.UIAxes,app.XData,app.YData, '-g');
            app.UIAxes.XLim = [1959, 2068];
            
            hold(app.UIAxes, 'on');
            
            app.Carbon_emissions = app.Carbon_emissions1;
            app.Graph_legend = {};
            app.legend_wedges = [];
            
            app.TotalCostofWedgesEditField.Value = 0;
            
            
            for j = 1:r1
                
                if app.UITable.Data{j, index10} == true && app.UITable.Data{j, index11} == 0
                    app.wedge_data{j, index11} = 1;
                    app.wedge_data{j, index10} = true;
                    app.UITable.Data = app.wedge_data;
                elseif app.UITable.Data{j, index10} == false && app.UITable.Data{j, index11} ~= 0
                    app.wedge_data{j, index11} = 0;
                    app.wedge_data{j, index10} = false;
                    app.UITable.Data = app.wedge_data;
                end % end if
                
                if app.wedge_data{j, index10} == true
                    Constraint = strsplit(app.wedge_data{j, index3}, ',');
                    for k = 1:numel(Constraint)
                        for h = 1:r2
                            if strcmp(string(Constraint(k)), string(app.Constraints{h, index4}))
                                app.Constraints{h, index5} = num2str(str2num(app.Constraints{h, index5}) - app.wedge_data{j, index11});
                                app.UITable2.Data = app.Constraints;
                            end % end if
                            if str2num(app.Constraints{h, index5}) < 0
                                warndlg('Too many files of desired sector. Please follow given constraints. (Please undo last action)', 'Error');
                                return
                            end % end if
                        end % end for
                    end % end for
                end % end if
                
                
                hold(app.UIAxes, 'on');
                m = (10*2^((2068-2017)/50)-10*2^((2018-2017)/50))/(2068-2018);
                b = (10*2^((2018-2017)/50)-m*2018);
                year = str2num(app.wedge_data{j, index13});
                
                if isempty(year) && app.wedge_data{j, index11} > 0
                    warndlg('Please enter a starting year');
                    return
                end % end if
                
                if strcmp(app.GraphDropDown.Value, 'Carbon Emissions')
                    if app.wedge_data{j, index10} == true
                        if str2num(app.wedge_data{j, index13}) > 2067
                            warndlg('Please choose a starting year between 2018 and 2067.', 'Error');
                            return
                        else
                            for c = 1:app.wedge_data{j, index11}
                                xdata1 = [year, 2068];
                                ydata1 = [m*year+b, app.Carbon_emissions];
                                plot(app.UIAxes,xdata1,ydata1, '-');
                                %
                                %
                                
                                app.Carbon_emissions = app.Carbon_emissions - (2068-str2num(app.wedge_data{j, index13}))/50;
                                
                                xdata2 = [year, 2068];
                                ydata2 = [m*year+b, app.Carbon_emissions];
                                plot(app.UIAxes, xdata2, ydata2, '-');
                                %
                                Category = app.wedge_data{j, index7};
                                k = [];
                                
                                if strcmp('Efficiency and Conservation', Category)
                                    k = fill(app.UIAxes, [xdata1, xdata2(2)], [ydata1, ydata2(2)], 'y');
                                elseif strcmp('Fossil Fuel', Category)
                                    k = fill(app.UIAxes, [xdata1, xdata2(2)], [ydata1, ydata2(2)], 'b');
                                elseif strcmp('Nuclear', Category)
                                    k = fill(app.UIAxes, [xdata1, xdata2(2)], [ydata1, ydata2(2)], 'r');
                                elseif strcmp('Renewables and Biostorage', Category)
                                    k = fill(app.UIAxes, [xdata1, xdata2(2)], [ydata1, ydata2(2)], 'g');
                                end % end if
                                
                                if numel(app.Graph_legend) == 0
                                    app.Graph_legend = {app.wedge_data{j, index9}};
                                    app.legend_wedges = [k];
                                    app.Legend = legend(app.UIAxes, app.legend_wedges, app.Graph_legend{:}, 'Location', 'northwest');
                                else
                                    app.Graph_legend = [app.Graph_legend, app.wedge_data{j, index9}];
                                    app.legend_wedges = [app.legend_wedges, k];
                                end % end if
                            end % end if
                            
                        end % end for
                        flatlinex = [str2num(app.wedge_data{j, index13}), 2068];
                        flatliney = [m*str2num(app.wedge_data{j, index13})+b, m*str2num(app.wedge_data{j, index13})+b];
                        
                        flatlineyear = str2num(app.wedge_data{j, index13});
                        
                        app.TotalCostofWedgesEditField.Value = app.TotalCostofWedgesEditField.Value + str2num(app.wedge_data{j, index12})*app.wedge_data{j, index11};


                    end % end if
                end % end if
            end % end for
            
            
            flatlineyear = str2num(app.wedge_data{j, index13});
            
            if strcmp(app.GraphDropDown.Value, 'Carbon Emissions')
                app.Carbon_emissions = app.Carbon_emissions1;
                
                plot(app.UIAxes, flatlinex, flatliney, 'k');
                app.Legend = legend(app.UIAxes, app.legend_wedges, app.Graph_legend{:}, 'Location', 'northwest');
                
                delete(app.Legend)
                app.Legend = legend(app.UIAxes, app.legend_wedges, app.Graph_legend{:}, 'Location', 'northwest');
            end % end if
            
            app.Count = 0;
            if strcmp(app.NumberofWedgestoReachDropDown.Value, 'flatline emissions')
                app.Count = 0;
                while app.Carbon_emissions > flatliney(1)
                    app.Count = app.Count + 1;
                    app.Carbon_emissions = app.Carbon_emissions - (2068-flatlineyear)/50;
                end % end while
                
                app.EditField.Value = sum([app.wedge_data{:, index11}]) + app.Count;
            elseif strcmp(app.NumberofWedgestoReachDropDown.Value, '1990 level emissions')
                app.Count = 0;
                while app.Carbon_emissions > 6.06
                    app.Count = app.Count + 1;
                    app.Carbon_emissions = app.Carbon_emissions - (2068-flatlineyear)/50;
                end % end while
            elseif strcmp(app.NumberofWedgestoReachDropDown.Value, '80% below 1990 level emissions')
                app.Count = 0;
                while app.Carbon_emissions > 6.06*0.2
                    app.Count = app.Count + 1;
                    app.Carbon_emissions = app.Carbon_emissions - (2068-flatlineyear)/50;
                end % end while
            end % end if
            
            app.EditField.Value = app.Count;
            
            app.TotalNumberofWedgesUsedEditField.Value = sum([app.wedge_data{:, index11}]);


        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            app.Constraints = app.Constraints1;
            app.UITable2.Data = app.Constraints;


            hold(app.UIAxes, 'off');
            
            plot(app.UIAxes,app.XData,app.YData, '-g');
            app.UIAxes.XLim = [1959, 2068];
            delete(legend(app.UIAxes));
            
            hold(app.UIAxes, 'on');
            
            app.wedge_data = app.wedge_data1;
            app.UITable.Data = app.wedge_data;
            app.TotalNumberofWedgesUsedEditField.Value = 0;
            app.TotalCostofWedgesEditField.Value = 0;
            app.EditField.Value = 0;
            
        end

        % Value changed function: NumberofWedgestoReachDropDown
        function NumberofWedgestoReachDropDownValueChanged(app, event)
            value = app.NumberofWedgestoReachDropDown.Value;
            [r1, ~] = size(app.wedge_data);
            
            header1 = app.UITable.ColumnName;
            header4 = app.UITable2.ColumnName;
            header5 = strsplit(app.Strategy_header, ' ');
            header7 = app.UITable2.ColumnName;
            header3 = strsplit(app.Strategy_header, ' ');
            
            index1 = find(contains(header1, 'Strategy'));
            index3 = find(contains(header1, 'Sector'));
            index4 = find(contains(header4, 'Sector'));
            index5 = find(contains(header4, 'Constraints'));
            index6 = find(contains(header3, 'ID'));
            index7 = find(contains(header1, 'Category'));
            index8 = find(contains(header5, 'YearStart'));
            index9 = find(contains(header1, 'StrategyName'));
            index10 = find(contains(header1, 'SelectedWedges'));
            index11 = find(contains(header1, 'NumberUsed'));
            index12 = find(contains(header1, 'Cost'));
            index13 = find(contains(header1, 'StartYear'));
            
            m = (10*2^((2068-2017)/50)-10*2^((2018-2017)/50))/(2068-2018);
            b = (10*2^((2018-2017)/50)-m*2018);
            
            for j = 1:r1
                
                if app.wedge_data{j, index10} == true
                    
                    flatlineyear = str2num(app.wedge_data{j, index13});
                    flatliney = m*str2num(app.wedge_data{j, index13})+b;
                    
                end % end if
                
            end % end for
            
            app.Carbon_emissions = app.Carbon_emissions1;
            
            if strcmp(value, 'flatline emissions')
                app.Count = 0;
                while app.Carbon_emissions > flatliney
                    app.Count = app.Count + 1;
                    app.Carbon_emissions = app.Carbon_emissions - (2068-flatlineyear)/50;
                end % end while
                
                app.EditField.Value = sum([app.wedge_data{:, index11}]) + app.Count;
            elseif strcmp(value, '1990 level emissions')
                app.Count = 0;
                while app.Carbon_emissions > 6.06
                    app.Count = app.Count + 1;
                    app.Carbon_emissions = app.Carbon_emissions - (2068-flatlineyear)/50;
                end % end while
            elseif strcmp(value, '80% below 1990 level emissions')
                app.Count = 0;
                while app.Carbon_emissions > 6.06*0.2
                    app.Count = app.Count + 1;
                    app.Carbon_emissions = app.Carbon_emissions - (2068-flatlineyear)/50;
                end % end while
            end % end if
            
            app.EditField.Value = app.Count;
            
        end

        % Value changed function: GraphDropDown
        function GraphDropDownValueChanged(app, event)
            value = app.GraphDropDown.Value;
            
            if strcmp(value, 'CO2 Emissions vs. Time')
            elseif strcmp(value, 'Mean Warming vs. Time')
            elseif strcmp(value, 'Carbon Emissions')
                
                [r1, ~] = size(app.wedge_data);
                
                header1 = app.UITable.ColumnName;
                header4 = app.UITable2.ColumnName;
                header5 = strsplit(app.Strategy_header, ' ');
                header7 = app.UITable2.ColumnName;
                app.UITable2.Data = app.Constraints1;
                header3 = strsplit(app.Strategy_header, ' ');
                
                index1 = find(contains(header1, 'Strategy'));
                index3 = find(contains(header1, 'Sector'));
                index4 = find(contains(header4, 'Sector'));
                index5 = find(contains(header4, 'Constraints'));
                index6 = find(contains(header3, 'ID'));
                index7 = find(contains(header1, 'Category'));
                index8 = find(contains(header5, 'YearStart'));
                index9 = find(contains(header1, 'StrategyName'));
                index10 = find(contains(header1, 'SelectedWedges'));
                index11 = find(contains(header1, 'NumberUsed'));
                index12 = find(contains(header1, 'Cost'));
                index13 = find(contains(header1, 'StartYear'));
                app.wedge_data = app.UITable.Data;
                app.Constraints = app.Constraints1;
                [r2, ~] = size(app.Constraints);
                
                hold(app.UIAxes, 'off');
                
                plot(app.UIAxes,app.XData,app.YData, '-g');
                app.UIAxes.XLim = [1959, 2068];
                
                hold(app.UIAxes, 'on');
                
                app.Carbon_emissions = app.Carbon_emissions1;
                app.Graph_legend = {};
                app.legend_wedges = [];
                
                app.TotalCostofWedgesEditField.Value = 0;
                
                
                for j = 1:r1
                    
                    if app.UITable.Data{j, index10} == true && app.UITable.Data{j, index11} == 0
                        app.wedge_data{j, index11} = 1;
                        app.wedge_data{j, index10} = true;
                        app.UITable.Data = app.wedge_data;
                    elseif app.UITable.Data{j, index10} == false && app.UITable.Data{j, index11} ~= 0
                        app.wedge_data{j, index11} = 0;
                        app.wedge_data{j, index10} = false;
                        app.UITable.Data = app.wedge_data;
                    end % end if
                    
                    if app.wedge_data{j, index10} == true
                        Constraint = strsplit(app.wedge_data{j, index3}, ',');
                        for k = 1:numel(Constraint)
                            for h = 1:r2
                                if strcmp(string(Constraint(k)), string(app.Constraints{h, index4}))
                                    app.Constraints{h, index5} = num2str(str2num(app.Constraints{h, index5}) - app.wedge_data{j, index11});
                                    app.UITable2.Data = app.Constraints;
                                end % end if
                                if str2num(app.Constraints{h, index5}) < 0
                                    warndlg('Too many files of desired sector. Please follow given constraints. (Please undo last action)', 'Error');
                                    return
                                end % end if
                            end % end for
                        end % end for
                    end % end if
                    
                    
                    hold(app.UIAxes, 'on');
                    m = (10*2^((2068-2017)/50)-10*2^((2018-2017)/50))/(2068-2018);
                    b = (10*2^((2018-2017)/50)-m*2018);
                    year = str2num(app.wedge_data{j, index13});
                    
                    if isempty(year) && app.wedge_data{j, index11} > 0
                        warndlg('Please enter a starting year');
                        return
                    end % end if
                    
                    if strcmp(app.GraphDropDown.Value, 'Carbon Emissions')
                        if app.wedge_data{j, index10} == true
                            if str2num(app.wedge_data{j, index13}) > 2067
                                warndlg('Please choose a starting year between 2018 and 2067.', 'Error');
                                return
                            else
                                for c = 1:app.wedge_data{j, index11}
                                    xdata1 = [year, 2068];
                                    ydata1 = [m*year+b, app.Carbon_emissions];
                                    plot(app.UIAxes,xdata1,ydata1, '-');
                                    %
                                    %
                                    
                                    app.Carbon_emissions = app.Carbon_emissions - (2068-str2num(app.wedge_data{j, index13}))/50;
                                    
                                    xdata2 = [year, 2068];
                                    ydata2 = [m*year+b, app.Carbon_emissions];
                                    plot(app.UIAxes, xdata2, ydata2, '-');
                                    %
                                    Category = app.wedge_data{j, index7};
                                    k = [];
                                    
                                    if strcmp('Efficiency and Conservation', Category)
                                        k = fill(app.UIAxes, [xdata1, xdata2(2)], [ydata1, ydata2(2)], 'y');
                                    elseif strcmp('Fossil Fuel', Category)
                                        k = fill(app.UIAxes, [xdata1, xdata2(2)], [ydata1, ydata2(2)], 'b');
                                    elseif strcmp('Nuclear', Category)
                                        k = fill(app.UIAxes, [xdata1, xdata2(2)], [ydata1, ydata2(2)], 'r');
                                    elseif strcmp('Renewables and Biostorage', Category)
                                        k = fill(app.UIAxes, [xdata1, xdata2(2)], [ydata1, ydata2(2)], 'g');
                                    end % end if
                                    
                                    if numel(app.Graph_legend) == 0
                                        app.Graph_legend = {app.wedge_data{j, index9}};
                                        app.legend_wedges = [k];
                                        app.Legend = legend(app.UIAxes, app.legend_wedges, app.Graph_legend{:}, 'Location', 'northwest');
                                    else
                                        app.Graph_legend = [app.Graph_legend, app.wedge_data{j, index9}];
                                        app.legend_wedges = [app.legend_wedges, k];
                                    end % end if
                                end % end if
                                
                            end % end for
                            flatlinex = [str2num(app.wedge_data{j, index13}), 2068];
                            flatliney = [m*str2num(app.wedge_data{j, index13})+b, m*str2num(app.wedge_data{j, index13})+b];
                            
                            flatlineyear = str2num(app.wedge_data{j, index13});
                            
                            app.TotalCostofWedgesEditField.Value = app.TotalCostofWedgesEditField.Value + str2num(app.wedge_data{j, index12})*app.wedge_data{j, index11};
                        elseif strcmp(app.GraphDropDown.Value, 'CO2 Concentration vs. Time')
                             if app.wedge_data{j, index10} == true
                            flatliney = [m*str2num(app.wedge_data{j, index13})+b, m*str2num(app.wedge_data{j, index13})+b];
                            
                            flatlineyear = str2num(app.wedge_data{j, index13});
                             end % end if
                            
                            
                        end % end if
                    end % end if
                end % end for
                
                app.Carbon_emissions = app.Carbon_emissions2;
                
                plot(app.UIAxes, flatlinex, flatliney, 'k');
                app.Legend = legend(app.UIAxes, app.legend_wedges, app.Graph_legend{:}, 'Location', 'northwest');
                
                delete(app.Legend)
                app.Legend = legend(app.UIAxes, app.legend_wedges, app.Graph_legend{:}, 'Location', 'northwest');
                
                app.Count = 0;
                while app.Carbon_emissions > flatliney(1)
                    app.Count = app.Count + 1;
                    app.Carbon_emissions = app.Carbon_emissions - (2068-flatlineyear)/50;
                end % end while
                
                app.EditField.Value = app.Count;
                
                app.TotalNumberofWedgesUsedEditField.Value = sum([app.wedge_data{:, index11}]);
                
            end % end if
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.WedgesFileNameWithoutQuotesEditFieldLabel = uilabel(app.UIFigure);
            app.WedgesFileNameWithoutQuotesEditFieldLabel.Text = 'Wedges File Name (Without Quotes)';
            app.WedgesFileNameWithoutQuotesEditField = uieditfield(app.UIFigure, 'text');
            app.WedgesFileNameWithoutQuotesEditField.Value = 'wedges.txt';
            app.ConstraintsFileNameWithoutQuotesEditFieldLabel = uilabel(app.UIFigure);
            app.ConstraintsFileNameWithoutQuotesEditFieldLabel.Text = 'Constraints File Name (Without Quotes)';
            app.ConstraintsFileNameWithoutQuotesEditField = uieditfield(app.UIFigure, 'text');
            app.ConstraintsFileNameWithoutQuotesEditField.Value = 'constraints.txt';
            app.StrategyFileNameWithoutQuotesEditFieldLabel = uilabel(app.UIFigure);
            app.StrategyFileNameWithoutQuotesEditFieldLabel.Text = 'Strategy File Name (Without Quotes)';
            app.StrategyFileNameWithoutQuotesEditField = uieditfield(app.UIFigure, 'text');
            app.StrategyFileNameWithoutQuotesEditField.Value = 'strategy.txt';
            app.ReadFilesButton = uibutton(app.UIFigure, 'push');
            app.ReadFilesButton.ButtonPushedFcn = createCallbackFcn(app, @ReadFilesButtonPushed, true);
            app.ReadFilesButton.Text = 'Read Files';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Carbon Emissions over Time')
            xlabel(app.UIAxes, 'Year')
            ylabel(app.UIAxes, 'Carbon Emissions (Gt/yr)')
            app.SavetheWorldLabel = uilabel(app.UIFigure);
            app.SavetheWorldLabel.Text = 'Save the World!';
            app.OutputStrategyButton = uibutton(app.UIFigure, 'push');
            app.OutputStrategyButton.ButtonPushedFcn = createCallbackFcn(app, @OutputStrategyButtonPushed, true);
            app.OutputStrategyButton.Text = 'Output Strategy';
            app.Step1ReadFilesLabel = uilabel(app.UIFigure);
            app.Step1ReadFilesLabel.Text = 'Step 1: Read Files';
            app.Step2SelectAdditionalWedgesLabel = uilabel(app.UIFigure);
            app.Step2SelectAdditionalWedgesLabel.Text = 'Step 2: Select Additional Wedges';
            app.Step3OutputStrategyLabel = uilabel(app.UIFigure);
            app.Step3OutputStrategyLabel.Text = 'Step 3: Output Strategy';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.WedgesLabel = uilabel(app.UIFigure);
            app.WedgesLabel.Text = 'Wedges';
            app.ConstraintsLabel = uilabel(app.UIFigure);
            app.ConstraintsLabel.Text = 'Constraints';
            app.ClearAllButton = uibutton(app.UIFigure, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.TotalNumberofWedgesUsedEditFieldLabel = uilabel(app.UIFigure);
            app.TotalNumberofWedgesUsedEditFieldLabel.Text = 'Total Number of Wedges Used';
            app.TotalNumberofWedgesUsedEditField = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = '';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalCostofWedgesEditFieldLabel = uilabel(app.UIFigure);
            app.TotalCostofWedgesEditFieldLabel.Text = 'Total Cost of Wedges';
            app.TotalCostofWedgesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofWedgestoReachDropDownLabel = uilabel(app.UIFigure);
            app.NumberofWedgestoReachDropDownLabel.Text = 'Number of Wedges to Reach';
            app.NumberofWedgestoReachDropDown = uidropdown(app.UIFigure);
            app.NumberofWedgestoReachDropDown.ValueChangedFcn = createCallbackFcn(app, @NumberofWedgestoReachDropDownValueChanged, true);
            app.GraphDropDownLabel = uilabel(app.UIFigure);
            app.GraphDropDownLabel.Text = 'Graph';
            app.GraphDropDown = uidropdown(app.UIFigure);
            app.GraphDropDown.ValueChangedFcn = createCallbackFcn(app, @GraphDropDownValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = climatewedges

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end